classdef GUI_GaussJordan_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        GaussJordanPanel               matlab.ui.container.Panel
        DropDown                       matlab.ui.control.DropDown
        uitable_Resultado              matlab.ui.control.Table
        uitable_Matriz                 matlab.ui.control.Table
        pushbutton_Calcular            matlab.ui.control.Button
        textArea_Resultado             matlab.ui.control.TextArea
        uipanelTamanoMatrizNoCuadrada  matlab.ui.container.Panel
        pushbutton_Aceptar             matlab.ui.control.Button
        pushbutton_Limpiar             matlab.ui.control.Button
        edit_Fila                      matlab.ui.control.EditField
        edit_Columna                   matlab.ui.control.EditField
        text_Resultado                 matlab.ui.control.Label
        pushbutton_Regresar            matlab.ui.control.Button
    end

    
    methods (Access = private)
        function setGlobalCantColumnas(~, val) %permite setear variable global para almacenar la cantidad de columnas
            global CantColumnas
            CantColumnas = val;
        end
        
        function CantColumnasR = getGlobalCantColumnas(~) %permite obtener variable global de la cantidad de columnas
            global CantColumnas
            CantColumnasR = CantColumnas;
        end
        
        function setGlobalCantFilas(~,val) %permite setear variable global para almacenar la cantidad de filas
            global CantFilas
            CantFilas = val;
        end
        
        function CantFilasR = getGlobalCantFilas(~) %permite obtener variable global de la cantidad de filas
            global CantFilas
            CantFilasR = CantFilas;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_GAUSSJORDAN_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_GAUSSJORDAN (see VARARGIN)
            
            % Choose default command line output for GUI_GAUSSJORDAN
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_Regresar
        function pushbutton_RegresarButtonPushed(app, event)
            closereq(); %cerrar panel actual
            GUI_Menu_App %regresar al panel de menú principal
        end

        % Button pushed function: pushbutton_Calcular
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            valor=get(handles.uitable_Matriz,'data'); %obtiene la matriz ingresada en las celdas
            valor=str2double(valor); %convierte la matriz de strings a doble
            
            if(isnan(valor)) %si la matriz es incorrecta
                warndlg('Debe ingresar valores para calcular Gauss Jordan','Precaución');
            else %si la matriz es correcta
                if(getGlobalCantColumnas(app)==getGlobalCantFilas(app)) %matriz cuadrada
                    
                else %matriz no cuadrada    
                    gaussJordanR= gauss_jordan(valor);
                
                    s=round(gaussJordanR);
                    set(handles.textArea_Resultado,'string',num2str(gaussJordanR));
                end
                
                
            end
        end

        % Button pushed function: pushbutton_Aceptar
        function pushbutton_AceptarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            filas=str2double(get(handles.edit_Fila,'String')); %obtiene el valor ingresado en el campo de filas convertido de string a double
            columnas=str2double(get(handles.edit_Columna,'String')); %obtiene el valor ingresado en el campo de columnas convertido de string a double
            
            if(isnan(columnas) || isnan(filas)) %alguno o ambos de los valores ingresados es inválido
                warndlg('El campo de filas y columnas no debe estar vacío, con letras o caracteres especiales','Precaución');
            else 
                if(filas==0 || columnas==0) %alguno o ambos de los valores ingresados es igual a cero
                    warndlg('El campo de filas y columnas debe ser mayor a 0','Precaución');
                    set(handles.edit_Fila,'String',''); %limpiar el campo de fila
                    set(handles.edit_Columna,'String',''); %limpiar el campo de columna
                else %los valores son válidos
                    set(handles.pushbutton_Calcular,'visible','on'); %hacer visible el botón de calcular
                    tamano=cell(filas,columnas); %devuelve una matriz de celdas de #filas por #columnas
                    tamano(:,:)={'0'}; %otorga a todas las celdas un 0
                    set(handles.uitable_Matriz,'Data',tamano); %setea las celdas
                    set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas)); %hace editables las celdas 
                    
                    setGlobalCantColumnas(app, columnas); %setea globalmente la cantidad de las columnas 
                    setGlobalCantFilas(app, filas); %setea globalmente la cantidad de las filas
                end
            end
        end

        % Button pushed function: pushbutton_Limpiar
        function pushbutton_LimpiarButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>       
            set(handles.textArea_Resultado,'string',''); %limpia la información que haya en el text area de resultado
            set(handles.edit_Fila,'string',''); %limpia la información que haya en el edit field de fila
            set(handles.edit_Columna,'string',''); %limpia la información que haya en el edit field de columna
            
            filas= 0;
            columnas= 0;
            
            tamano=cell(filas,columnas);
            tamano(:,:)={'0'};
            set(handles.uitable_Matriz,'Data',tamano);
            set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_GAUSSJORDAN';
            app.figure1.Tag = 'figure1';
            app.GaussJordanPanel = uipanel(app.figure1);
            app.GaussJordanPanel.Title = 'Gauss-Jordan';
            app.DropDown = uidropdown(app.GaussJordanPanel);
            app.uitable_Resultado = uitable(app.GaussJordanPanel);
            app.uitable_Resultado.Tag = 'uitable_Resultado';
            app.uitable_Matriz = uitable(app.GaussJordanPanel);
            app.uitable_Matriz.Tag = 'uitable_Matriz';
            app.pushbutton_Calcular = uibutton(app.GaussJordanPanel, 'push');
            app.pushbutton_Calcular.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.pushbutton_Calcular.Tag = 'pushbutton_Calcular';
            app.pushbutton_Calcular.Text = 'Calcular';
            app.textArea_Resultado = uitextarea(app.GaussJordanPanel);
            app.textArea_Resultado.Tag = 'textArea_Resultado';
            app.textArea_Resultado.Editable = 'off';
            app.uipanelTamanoMatrizNoCuadrada = uipanel(app.GaussJordanPanel);
            app.uipanelTamanoMatrizNoCuadrada.Title = 'Definir tamaño de la matriz';
            app.uipanelTamanoMatrizNoCuadrada.Tag = 'uipanel_TamanoMatrizNoCuadrada';
            app.pushbutton_Aceptar = uibutton(app.uipanelTamanoMatrizNoCuadrada, 'push');
            app.pushbutton_Aceptar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AceptarButtonPushed, true);
            app.pushbutton_Aceptar.Tag = 'pushbutton_Aceptar';
            app.pushbutton_Aceptar.Text = 'Aceptar';
            app.pushbutton_Limpiar = uibutton(app.uipanelTamanoMatrizNoCuadrada, 'push');
            app.pushbutton_Limpiar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_LimpiarButtonPushed, true);
            app.pushbutton_Limpiar.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar.Text = 'Limpiar';
            app.edit_Fila = uieditfield(app.uipanelTamanoMatrizNoCuadrada, 'text');
            app.edit_Fila.Tag = 'edit_Fila';
            app.edit_Columna = uieditfield(app.uipanelTamanoMatrizNoCuadrada, 'text');
            app.edit_Columna.Tag = 'edit_Columna';
            app.text_Resultado = uilabel(app.GaussJordanPanel);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            app.pushbutton_Regresar = uibutton(app.figure1, 'push');
            app.pushbutton_Regresar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_RegresarButtonPushed, true);
            app.pushbutton_Regresar.Tag = 'pushbutton_Regresar';
            app.pushbutton_Regresar.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_GaussJordan_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_GAUSSJORDAN_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end