classdef GUI_Menu_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        uipanel1                      matlab.ui.container.Panel
        button_DeterminanteMatriz     matlab.ui.control.Button
        button_GaussJordan            matlab.ui.control.Button
        button_Informacion            matlab.ui.control.Button
        button_TrazadorCubico         matlab.ui.control.Button
        buttton_MetodosNewton         matlab.ui.control.Button
        button_ReglaCompuestaSimpson  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function Funcion_CreateFcn(aapp, hObject, eventdata, handles)
            % hObject    handle to Funcion (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function button_Salir_Callback(app, hObject, eventdata, handles)
            % hObject    handle to button_Salir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            warndlg('Fin del programa')
            delete(hObject);
        end
        
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            
            syms x;
            %fx= str2sym(get(handles.Funcion,'String'));
            fx= (get(handles.Funcion,'String'));
            funTemp=str2sym(fx);
            a= str2double(get(handles.valorA,'String'));
            b= str2double(get(handles.valorB,'String'));
            n= str2double(get(handles.valorN,'String'));
            if  rem(n,2)==1 || isnan(a) || isnan(b) || isnan(n) || isempty(fx) || n<0
                mensaje=msgbox('Estimado Usuario, has ingresado caracteres incorrectos!','Error','error');
            else
                f = inline(fx);
                derivada1=diff(funTemp);
            
                derivada2=diff(derivada1);
                derivada3=diff(derivada2);
                derivada4=diff(derivada3);
                set(handles.d1,'String',sprintf('%s',derivada1));
                set(handles.d2,'String',sprintf('%s',derivada2));
                set(handles.d3,'String',sprintf('%s',derivada3));
                set(handles.d4,'String',sprintf('%s',derivada4));
                %fprintf('El Valor de l:%s ',derivada1)
            
                h=(b-a)/n;
                fprintf('El Valor de H es:%9.9f \n\n ',h)
                set(handles.valorH,'String',sprintf('%9.9f',h));
            
                %     if rem(n,2)==1
                %         fprintf('\n Inválido valor de n!!!');
                %         n=input('\n Recordá que el valor de n tiene que ser par. ');
                %     end
                for k=1:1:n
                    x(k)=a+k*h;
                    y(k)=f(x(k));
                end
                so=0;se=0;
                for k=1:1:n-1
                    if rem(k,2)==1
                        so=so+y(k);%Sumo pares
                    else
                        se=se+y(k); %Sumo Impares
                    end
                end
            
                gx = inline(derivada4);
                puntoMedio= (a+b)/2; %
                errorDerivada=gx(puntoMedio); %Evaluamos en la 4ta derivada, el valor medio calculado
                error=-((b-a)/180)*h^4*errorDerivada;%Calculamos el Error
            
            
                set(handles.valorError,'String',sprintf('%s',error)); %Mostramos el error por pantalla
            
                answer=h/3*(f(a)+f(b)+4*so+2*se);%Aplicamos la formula de la RCS
                set(handles.valorIntegral,'String',sprintf('%9.9f',answer)); %Mostramos valor aproximacion en pantalla
                % elseif rem(a,2)==1
                %    advertencia=msgbox('El N debe ser un valor PAR!');
            end
        end
        
        function pushbutton2_Callback(app, hObject, eventdata, handles)
            set(handles.valorIntegral,'String','');
            set(handles.valorH,'String','');
            set(handles.valorError,'String','');
            set(handles.valorN,'String','');
            set(handles.valorA,'String','');
            set(handles.valorB,'String','');
            set(handles.Funcion,'String','');
            set(handles.d1,'String','');
            set(handles.d2,'String','');
            set(handles.d3,'String','');
            set(handles.d4,'String','');
        end
        
        function valorA_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to valorA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function valorB_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to valorB (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function valorN_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to valorN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_MENU_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_MENU (see VARARGIN)
            
            % Choose default command line output for GUI_MENU
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_DeterminanteMatriz
        function button_DeterminanteMatriz_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_DeterminanteMatriz (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closereq();
            GUI_Det_App
        end

        % Button pushed function: button_ReglaCompuestaSimpson
        function button_ReglaCompuestaSimpson_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_ReglaCompuestaSimpson (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closereq();
            GUI_RCS_App
        end

        % Button pushed function: button_GaussJordan
        function button_GaussJordanButtonPushed(app, event)
            closereq();
            GUI_GaussJordan_App
        end

        % Button pushed function: button_Informacion
        function button_ManualUsuarioButtonPushed(app, event)
            winopen('manual_usuario.pdf')
        end

        % Button pushed function: button_TrazadorCubico
        function button_TrazadorCubicoButtonPushed(app, event)
            closereq();
            GUI_TrazadorCubico_App
        end

        % Button pushed function: buttton_MetodosNewton
        function buttton_MetodosNewtonButtonPushed(app, event)
            closereq();
            GUI_Newton_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_MENU';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Menú principal';
            app.uipanel1.Tag = 'uipanel1';
            app.button_DeterminanteMatriz = uibutton(app.uipanel1, 'push');
            app.button_DeterminanteMatriz.ButtonPushedFcn = createCallbackFcn(app, @button_DeterminanteMatriz_Callback, true);
            app.button_DeterminanteMatriz.Tag = 'button_DeterminanteMatriz';
            app.button_DeterminanteMatriz.Text = 'Determinante de una Matriz';
            app.button_GaussJordan = uibutton(app.uipanel1, 'push');
            app.button_GaussJordan.ButtonPushedFcn = createCallbackFcn(app, @button_GaussJordanButtonPushed, true);
            app.button_GaussJordan.Tag = 'button_GaussJordan';
            app.button_GaussJordan.Text = 'Gauss-Jordan';
            app.button_Informacion = uibutton(app.uipanel1, 'push');
            app.button_Informacion.ButtonPushedFcn = createCallbackFcn(app, @button_ManualUsuarioButtonPushed, true);
            app.button_Informacion.Tag = 'button_Informacion';
            app.button_Informacion.Text = 'Manual de usuario';
            app.button_TrazadorCubico = uibutton(app.uipanel1, 'push');
            app.button_TrazadorCubico.ButtonPushedFcn = createCallbackFcn(app, @button_TrazadorCubicoButtonPushed, true);
            app.button_TrazadorCubico.Tag = 'button_TrazadorCubico';
            app.button_TrazadorCubico.Text = 'Trazor Cubico';
            app.buttton_MetodosNewton = uibutton(app.uipanel1, 'push');
            app.buttton_MetodosNewton.ButtonPushedFcn = createCallbackFcn(app, @buttton_MetodosNewtonButtonPushed, true);
            app.buttton_MetodosNewton.Tag = 'buttton_MetodosNewton';
            app.buttton_MetodosNewton.Text = 'Metodos Newton';
            app.button_ReglaCompuestaSimpson = uibutton(app.uipanel1, 'push');
            app.button_ReglaCompuestaSimpson.ButtonPushedFcn = createCallbackFcn(app, @button_ReglaCompuestaSimpson_Callback, true);
            app.button_ReglaCompuestaSimpson.Tag = 'button_ReglaCompuestaSimpson';
            app.button_ReglaCompuestaSimpson.Text = 'Regla Compuesta de Simpson';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Menu_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_MENU_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end