classdef GUI_NEWTON_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        MetododeNewtonPanel    matlab.ui.container.Panel
        textFuncion            matlab.ui.control.Label
        textTolerancia         matlab.ui.control.Label
        button_Regresar        matlab.ui.control.Button
        textValorInicial       matlab.ui.control.Label
        textIteraciones        matlab.ui.control.Label
        pushbuttonResultado    matlab.ui.control.Button
        pushbutton_Limpiar_2   matlab.ui.control.Button
        edit_tolerancia        matlab.ui.control.NumericEditField
        edit_valorInicial      matlab.ui.control.NumericEditField
        edit_iteraciones       matlab.ui.control.NumericEditField
        edit_funcion           matlab.ui.control.EditField
        pushbuttonAceptar      matlab.ui.control.Button
        DatosDropDown          matlab.ui.control.DropDown
        QuedeseacalcularLabel  matlab.ui.control.Label
        TextAreaResultado      matlab.ui.control.TextArea
        text_Resultado         matlab.ui.control.Label
        edit_valorFinal        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_NEWTON_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_NEWTON (see VARARGIN)
            
            % Choose default command line output for GUI_NEWTON
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_Regresar
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton_Limpiar_2
        function button_Limpiar_Callback(app, event)
         
            set(app.textValorInicial,'visible','off');
            set(app.edit_valorInicial,'visible','off');
            set(app.edit_valorFinal,'visible','off');
            set(app.textFuncion,'visible','off');
            set(app.edit_funcion,'visible','off');
            set(app.TextAreaResultado,'visible','off');
            set(app.text_Resultado,'visible','off');
            set(app.pushbuttonResultado,'visible','off');
            set(app.textIteraciones,'visible','off');
            set(app.edit_iteraciones,'visible','off');
            set(app.textTolerancia,'visible','off');
            set(app.edit_tolerancia,'visible','off ');
        end

        % Button pushed function: pushbuttonAceptar
        function button_Aceptar_Callback(app, event)
            set(app.textValorInicial,'visible','on');
            set(app.edit_valorInicial,'visible','on');
            set(app.edit_valorFinal,'visible','on');
            set(app.textFuncion,'visible','on');
            set(app.edit_funcion,'visible','on');
            set(app.TextAreaResultado,'visible','on');
            set(app.text_Resultado,'visible','on');
            set(app.pushbuttonResultado,'visible','on');
            if(strcmp(app.DatosDropDown.Value,"Tolerancia"))
                set(app.textTolerancia,'visible','on');
                set(app.edit_tolerancia,'visible','on');
                 set(app.textIteraciones,'visible','off');
                set(app.edit_iteraciones,'visible','off');

            else
                set(app.textIteraciones,'visible','on');
                set(app.edit_iteraciones,'visible','on');
                set(app.textTolerancia,'visible','off');
                set(app.edit_tolerancia,'visible','off ');
              
            end
            
            
        end

        % Button pushed function: pushbuttonResultado
        function button_Resultado_Callback(app, event)
            cf=app.edit_funcion.Value;
            x = app.edit_valorInicial.Value;
            inicioIntervalo=x;
            finalIntervalo=app.edit_valorFinal.Value;
            if(strcmp(app.DatosDropDown.Value,"Tolerancia")) 
                
                f = inline(cf);
               
               
                [derivada]=Derivada(f);
                df = inline(derivada);
                tolerancia = app.edit_tolerancia.Value;
                bandera=true;
                n=0;
                divisorDecimales=1
                while(n<tolerancia)
                    divisorDecimales=(divisorDecimales*10);
                    n=n+1;
                end
                cantidadDecimales= 0.1/(divisorDecimales);
                n=0;
                while (bandera)             
                    xAnterior=x - rem(x,cantidadDecimales);
                    x = x - (f(x) / df(x));   
                    xTruncado=x - rem(x,cantidadDecimales);
                    if(xAnterior==xTruncado)
                       bandera=false; 
                    end
                    n=n+1;
                end
                app.TextAreaResultado.Value ="El P número "+n+" es: "+x;       
            else
                format long;
                f = inline(cf); % Transforma el String en Funcion
               
                
                [derivada]=Derivada(f);
                df = inline(derivada);% Transforma el String en Funcion
                
                n = 0;
                Iter=app.edit_iteraciones.Value;
                while (Iter>n)  
                    
                    x = (x - ((f(x)) / (df(x))));
                    n=n+1;
                end
               
                app.TextAreaResultado.Value ="El P número "+Iter+" es: "+x;         
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_NEWTON';
            app.figure1.Tag = 'figure1';
            app.MetododeNewtonPanel = uipanel(app.figure1);
            app.MetododeNewtonPanel.Title = 'Metodo de Newton';
            app.textFuncion = uilabel(app.figure1);
            app.textFuncion.Tag = 'text3';
            app.textFuncion.Visible = 'off';
            app.textFuncion.Text = 'Ingrese la función de f (x) :';
            app.textTolerancia = uilabel(app.figure1);
            app.textTolerancia.Tag = 'text4';
            app.textTolerancia.Visible = 'off';
            app.textTolerancia.Text = 'Ingrese la cantidad de decimales exactos en un numero positivo:';
            app.button_Regresar = uibutton(app.figure1, 'push');
            app.button_Regresar.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.button_Regresar.Tag = 'regresarbutton';
            app.button_Regresar.Text = 'Regresar';
            app.textValorInicial = uilabel(app.figure1);
            app.textValorInicial.Tag = 'text5';
            app.textValorInicial.Visible = 'off';
            app.textValorInicial.Text = 'Ingrese el intervalo:';
            app.textIteraciones = uilabel(app.figure1);
            app.textIteraciones.Tag = 'text6';
            app.textIteraciones.Visible = 'off';
            app.textIteraciones.Text = 'Ingrese el número de iteraciones:';
            app.pushbuttonResultado = uibutton(app.figure1, 'push');
            app.pushbuttonResultado.ButtonPushedFcn = createCallbackFcn(app, @button_Resultado_Callback, true);
            app.pushbuttonResultado.Tag = 'pushbutton_Aceptar';
            app.pushbuttonResultado.Visible = 'off';
            app.pushbuttonResultado.Text = 'Resultado';
            app.pushbutton_Limpiar_2 = uibutton(app.figure1, 'push');
            app.pushbutton_Limpiar_2.ButtonPushedFcn = createCallbackFcn(app, @button_Limpiar_Callback, true);
            app.pushbutton_Limpiar_2.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar_2.Text = 'Limpiar';
            app.edit_tolerancia = uieditfield(app.figure1, 'numeric');
            app.edit_tolerancia.Visible = 'off';
            app.edit_valorInicial = uieditfield(app.figure1, 'numeric');
            app.edit_valorInicial.Visible = 'off';
            app.edit_iteraciones = uieditfield(app.figure1, 'numeric');
            app.edit_iteraciones.Visible = 'off';
            app.edit_funcion = uieditfield(app.figure1, 'text');
            app.edit_funcion.Visible = 'off';
            app.pushbuttonAceptar = uibutton(app.figure1, 'push');
            app.pushbuttonAceptar.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.pushbuttonAceptar.Text = 'Aceptar';
            app.DatosDropDown = uidropdown(app.figure1);
            app.DatosDropDown.Items = {'Tolerancia', 'Numero de iteraciones'};
            app.DatosDropDown.Value = 'Tolerancia';
            app.QuedeseacalcularLabel = uilabel(app.figure1);
            app.QuedeseacalcularLabel.Text = '¿Que desea calcular?';
            app.TextAreaResultado = uitextarea(app.figure1);
            app.TextAreaResultado.Tag = 'textArea_Resultado';
            app.TextAreaResultado.Editable = 'off';
            app.TextAreaResultado.Visible = 'off';
            app.text_Resultado = uilabel(app.figure1);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Visible = 'off';
            app.text_Resultado.Text = 'Resultado';
            app.edit_valorFinal = uieditfield(app.figure1, 'numeric');
            app.edit_valorFinal.Visible = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_NEWTON_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_NEWTON_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end