classdef GUI_RCS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel1         matlab.ui.container.Panel
        pushbutton1      matlab.ui.control.Button
        valorB           matlab.ui.control.EditField
        Funcion          matlab.ui.control.EditField
        valorA           matlab.ui.control.EditField
        valorN           matlab.ui.control.EditField
        text3            matlab.ui.control.Label
        valorH           matlab.ui.control.Label
        text5            matlab.ui.control.Label
        valorIntegral    matlab.ui.control.Label
        text7            matlab.ui.control.Label
        valorError       matlab.ui.control.Label
        pushbutton2      matlab.ui.control.Button
        text9            matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text11           matlab.ui.control.Label
        text12           matlab.ui.control.Label
        text13           matlab.ui.control.Label
        text14           matlab.ui.control.Label
        text15           matlab.ui.control.Label
        text16           matlab.ui.control.Label
        d1               matlab.ui.control.Label
        d2               matlab.ui.control.Label
        d3               matlab.ui.control.Label
        d4               matlab.ui.control.Label
        button_Regresar  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_RCS_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_RCS (see VARARGIN)
            
            % Choose default command line output for GUI_RCS
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_Regresar
        function button_Regresar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton1
        function button_Aceptar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            syms x;
            %fx= str2sym(get(handles.Funcion,'String'));
            fx= (get(handles.Funcion,'String'));
            funTemp=str2sym(fx);
            a= str2double(get(handles.valorA,'String'));
            b= str2double(get(handles.valorB,'String'));
            n= str2double(get(handles.valorN,'String'));
            if  rem(n,2)==1 || isnan(a) || isnan(b) || isnan(n) || isempty(fx) || n<0
                mensaje=msgbox('Estimado Usuario, has ingresado caracteres incorrectos!','Error','error');
            else
                f = inline(fx);
                derivada1=diff(funTemp);
            
                derivada2=diff(derivada1);
                derivada3=diff(derivada2);
                derivada4=diff(derivada3);
                set(handles.d1,'String',sprintf('%s',derivada1));
                set(handles.d2,'String',sprintf('%s',derivada2));
                set(handles.d3,'String',sprintf('%s',derivada3));
                set(handles.d4,'String',sprintf('%s',derivada4));
                %fprintf('El Valor de l:%s ',derivada1)
            
                h=(b-a)/n;
                fprintf('El Valor de H es:%9.9f \n\n ',h)
                set(handles.valorH,'String',sprintf('%9.9f',h));
            
                %     if rem(n,2)==1
                %         fprintf('\n Inválido valor de n!!!');
                %         n=input('\n Recordá que el valor de n tiene que ser par. ');
                %     end
                for k=1:1:n
                    x(k)=a+k*h;
                    y(k)=f(x(k));
                end
                so=0;se=0;
                for k=1:1:n-1
                    if rem(k,2)==1
                        so=so+y(k);%Sumo pares
                    else
                        se=se+y(k); %Sumo Impares
                    end
                end
            
                gx = inline(derivada4);
                puntoMedio= (a+b)/2; %
                errorDerivada=gx(puntoMedio); %Evaluamos en la 4ta derivada, el valor medio calculado
                error=-((b-a)/180)*h^4*errorDerivada;%Calculamos el Error
            
            
                set(handles.valorError,'String',sprintf('%s',error)); %Mostramos el error por pantalla
            
                answer=h/3*(f(a)+f(b)+4*so+2*se);%Aplicamos la formula de la RCS
                set(handles.valorIntegral,'String',sprintf('%9.9f',answer)); %Mostramos valor aproximacion en pantalla
                % elseif rem(a,2)==1
                %    advertencia=msgbox('El N debe ser un valor PAR!');
            end
        end

        % Button pushed function: pushbutton2
        function button_Limpiar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(handles.valorIntegral,'String','');
            set(handles.valorH,'String','');
            set(handles.valorError,'String','');
            set(handles.valorN,'String','');
            set(handles.valorA,'String','');
            set(handles.valorB,'String','');
            set(handles.Funcion,'String','');
            set(handles.d1,'String','');
            set(handles.d2,'String','');
            set(handles.d3,'String','');
            set(handles.d4,'String','');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_RCS';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Regla Compuesta de Simpson';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.pushbutton1.Tag = 'button_Aceptar';
            app.pushbutton1.Text = 'Aceptar';
            app.valorB = uieditfield(app.uipanel1, 'text');
            app.valorB.Tag = 'valorB';
            app.valorB.Tooltip = 'Campo Numerico.';
            app.Funcion = uieditfield(app.uipanel1, 'text');
            app.Funcion.Tag = 'Funcion';
            app.valorA = uieditfield(app.uipanel1, 'text');
            app.valorA.Tag = 'valorA';
            app.valorN = uieditfield(app.uipanel1, 'text');
            app.valorN.Tag = 'valorN';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Valor H';
            app.valorH = uilabel(app.uipanel1);
            app.valorH.Tag = 'valorH';
            app.valorH.Text = '...';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Valor Integral RCS:';
            app.valorIntegral = uilabel(app.uipanel1);
            app.valorIntegral.Tag = 'valorIntegral';
            app.valorIntegral.Text = '...';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = ' El error es:';
            app.valorError = uilabel(app.uipanel1);
            app.valorError.Tag = 'valorError';
            app.valorError.Text = '...';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @button_Limpiar_Callback, true);
            app.pushbutton2.Tag = 'button_Limpiar';
            app.pushbutton2.Text = 'Limpiar';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Ingrese la Función: F(x) Omita Mayusculas';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Valor Númerico. Valor Intervalo A';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Valor Númerico. Valor Intervalo B';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Valor Númerico.Valor  N: PAR';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Derivada 1:';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Derivada 2:';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Derivada 3:';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Derivada 4:';
            app.d1 = uilabel(app.uipanel1);
            app.d1.Tag = 'd1';
            app.d1.Text = '...';
            app.d2 = uilabel(app.uipanel1);
            app.d2.Tag = 'd2';
            app.d2.Text = '...';
            app.d3 = uilabel(app.uipanel1);
            app.d3.Tag = 'd3';
            app.d3.Text = '...';
            app.d4 = uilabel(app.uipanel1);
            app.d4.Tag = 'd4';
            app.d4.Text = '....';
            app.button_Regresar = uibutton(app.uipanel1, 'push');
            app.button_Regresar.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.button_Regresar.Tag = 'button_Regresar';
            app.button_Regresar.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_RCS_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_RCS_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end