classdef GUI_TRAZADORCUBICO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MetodoTrazadorCubicoPanel       matlab.ui.container.Panel
        textoFuncion                    matlab.ui.control.Label
        uitable1                        matlab.ui.control.Table
        funcionEdit                     matlab.ui.control.EditField
        QueDatostieneDropDownLabel      matlab.ui.control.Label
        DatosDropDown                   matlab.ui.control.DropDown
        CantidaddepuntosEditFieldLabel  matlab.ui.control.Label
        cantidadPuntos                  matlab.ui.control.NumericEditField
        pushbutton_Aceptar              matlab.ui.control.Button
        ResultadoButton                 matlab.ui.control.Button
        RegresarButton                  matlab.ui.control.Button
        TextAreaResultado               matlab.ui.control.TextArea
        text_Resultado                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegresarButton
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton_Aceptar
        function pushbutton_AceptarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            if(app.cantidadPuntos.Value~=0 && app.cantidadPuntos.Value>0 )
            if(strcmp(app.DatosDropDown.Value,"Imagenes"))
            filas=app.cantidadPuntos.Value; 
            columnas=2;
                if(filas==0 || columnas==0)
                    warndlg('La cantidad de puntos debe ser mayor a 0','Precaución');
                    set(app.cantidadPuntos.Value,'String','');
                else
                    
                    set(app.ResultadoButton,'visible','on');
                    tamano=cell(filas,columnas);
                    tamano(:,:)={'0'};
                    set(app.uitable1,'visible','on');
                    set(app.uitable1,'Data',tamano);
                    set(app.uitable1,'ColumnEditable',true(1,columnas));
               
            end     
         
            else
                set(app.funcionEdit,'visible','on');
                set(app.textoFuncion,'visible','on');
                filas=app.cantidadPuntos.Value; 
                columnas=1;
                set(app.ResultadoButton,'visible','on');
                tamano=cell(filas,columnas);
                tamano(:,:)={'0'};
                set(app.uitable1,'visible','on');
                set(app.uitable1,'Data',tamano);
                set(app.uitable1,'ColumnEditable',true(1,columnas));
                
              
            end
            else
              warndlg('La cantidad de puntos debe ser mayor a 0','Precaución');  
            end
        end

        % Button pushed function: ResultadoButton
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
             if(strcmp(app.DatosDropDown.Value,"Imagenes"))%cuando selecciona imagenes
              matriz=app.uitable1.Data;
              matrizX=[];
              matrizY=[];
              for x = 1:size(matriz,1) %sacamos los puntos
                matrizX(x,1)=str2double(matriz(x,1));  
              end
              for y = 1:size(matriz,1) %sacamos las imagenes
                matrizY(y,1)=str2double(matriz(y,2));  
              end
             else%cuando seleccionamos funcion
              matriz=app.uitable1.Data;
              matrizX=[];
              matrizY=[];
              for x = 1:size(matriz,1) %sacamos los puntos
                matrizX(x,1)=str2double(matriz(x,1));  
              end
              syms x
              f=str2func(['@(x)',app.funcionEdit.Value]);
              for x = 1:size(matrizX,1)%sacamos las imagenes de los puntos 
                matrizY(x,1)=subs(f,matrizX(x,1));
              end

             end
            %Datos
            matrizValores =[matrizX matrizY] ;%igualamos las matrices
            
            tamanoMatriz=length(matrizValores(1,:));%determinamos su tamaño

            for i=1:tamanoMatriz;
                a(i)=matrizValores(2,i); % igualamos las imagenes a los a
            end
            
            for i=1:tamanoMatriz-1;
                h(i)=matrizValores(1,i+1)-matrizValores(1,i);%determinamos los h
            end
            
            for i=2:tamanoMatriz-1;
                alfa(i)=3/h(i)*(a(i+1)-a(i))-3/h(i-1)*(a(i)-a(i-1));
            end
            
            l(1)=1;
            mu(1)=0;
            z(1)=0;
            
            for i=2:tamanoMatriz-1;
                l(i)=2*(matrizValores(1,i+1)-matrizValores(1,i-1))-h(i-1)*mu(i-1);
                mu(i)=h(i)/l(i);
                z(i)=(alfa(i)-h(i-1)*z(i-1))/l(i);
            end
            
            l(tamanoMatriz)=1;
            z(tamanoMatriz)=0;
            c(tamanoMatriz)=0;
            
            for i=tamanoMatriz-1:-1:1;
                c(i)=z(i)-mu(i)*c(i+1);
                b(i)=(a(i+1)-a(i))/h(i)-h(i)*(c(i+1)+2*c(i))/3;
                d(i)=(c(i+1)-c(i))/(3*h(i));
            end
            %Calculamos el resultado de los polinomios del metodo
            for i=1:tamanoMatriz-1;
                matrizValores=matrizValores(1,i):0.1:matrizValores(1,i+1);
                y=a(i)+b(i)*(matrizValores-matrizValores(1,i))+c(i)*(matrizValores-matrizValores(1,i)).^2+d(i)*(matrizValores-matrizValores(1,i)).^3;
                resultado=""+y;
            end
           
            app.TextAreaResultado.Value =""+ resultado;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetodoTrazadorCubicoPanel = uipanel(app.UIFigure);
            app.MetodoTrazadorCubicoPanel.Title = 'Metodo Trazador Cubico';
            app.textoFuncion = uilabel(app.MetodoTrazadorCubicoPanel);
            app.textoFuncion.Visible = 'off';
            app.textoFuncion.Text = 'Ingrese la función ';
            app.uitable1 = uitable(app.MetodoTrazadorCubicoPanel);
            app.uitable1.Visible = 'off';
            app.uitable1.Tag = 'uitable1';
            app.funcionEdit = uieditfield(app.MetodoTrazadorCubicoPanel, 'text');
            app.funcionEdit.Visible = 'off';
            app.QueDatostieneDropDownLabel = uilabel(app.MetodoTrazadorCubicoPanel);
            app.QueDatostieneDropDownLabel.Text = '¿Que Datos tiene?';
            app.DatosDropDown = uidropdown(app.MetodoTrazadorCubicoPanel);
            app.DatosDropDown.Items = {'Imagenes', 'Función'};
            app.DatosDropDown.Value = 'Imagenes';
            app.CantidaddepuntosEditFieldLabel = uilabel(app.MetodoTrazadorCubicoPanel);
            app.CantidaddepuntosEditFieldLabel.Text = 'Cantidad de puntos';
            app.cantidadPuntos = uieditfield(app.MetodoTrazadorCubicoPanel, 'numeric');
            app.pushbutton_Aceptar = uibutton(app.MetodoTrazadorCubicoPanel, 'push');
            app.pushbutton_Aceptar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AceptarButtonPushed, true);
            app.pushbutton_Aceptar.Text = 'Aceptar';
            app.ResultadoButton = uibutton(app.MetodoTrazadorCubicoPanel, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.ResultadoButton.Visible = 'off';
            app.ResultadoButton.Text = 'Resultado';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.RegresarButton.Text = 'Regresar';
            app.TextAreaResultado = uitextarea(app.UIFigure);
            app.TextAreaResultado.Tag = 'textArea_Resultado';
            app.TextAreaResultado.Editable = 'off';
            app.text_Resultado = uilabel(app.UIFigure);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TRAZADORCUBICO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end