classdef GUI_Newton_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        MetododeNewtonPanel   matlab.ui.container.Panel
        button_Regresar       matlab.ui.control.Button
        TextAreaResultado     matlab.ui.control.TextArea
        text_Resultado        matlab.ui.control.Label
        pushbuttonCalcular    matlab.ui.control.Button
        edit_iteraciones      matlab.ui.control.NumericEditField
        textIteraciones       matlab.ui.control.Label
        edit_tolerancia       matlab.ui.control.NumericEditField
        textTolerancia        matlab.ui.control.Label
        textFuncion           matlab.ui.control.Label
        edit_valorInicial     matlab.ui.control.NumericEditField
        textValorInicial      matlab.ui.control.Label
        edit_funcion          matlab.ui.control.EditField
        pushbutton_Limpiar_2  matlab.ui.control.Button
        pushbuttonAceptar     matlab.ui.control.Button
        DatosDropDown         matlab.ui.control.DropDown
        QudeseacalcularLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_NEWTON_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_NEWTON (see VARARGIN)
            
            % Choose default command line output for GUI_NEWTON
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_Regresar
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton_Limpiar_2
        function button_Limpiar_Callback(app, event)
            set(app.textValorInicial,'visible','off');
            set(app.edit_valorInicial,'visible','off');
            set(app.textFuncion,'visible','off');
            set(app.edit_funcion,'visible','off');
            set(app.TextAreaResultado,'visible','off');
            set(app.text_Resultado,'visible','off');
            set(app.pushbuttonCalcular,'visible','off');
            set(app.textIteraciones,'visible','off');
            set(app.edit_iteraciones,'visible','off');
            set(app.textTolerancia,'visible','off');
            set(app.edit_tolerancia,'visible','off ');
        end

        % Button pushed function: pushbuttonAceptar
        function button_Aceptar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            set(app.textValorInicial,'visible','on');
            set(app.edit_valorInicial,'visible','on');
            set(app.textFuncion,'visible','on');
            set(app.edit_funcion,'visible','on');
            set(app.TextAreaResultado,'visible','on');
            set(app.text_Resultado,'visible','on');
            set(app.pushbuttonCalcular,'visible','on');
            set(handles.TextAreaResultado,'string',''); 
            
            if(strcmp(app.DatosDropDown.Value,"Tolerancia"))
                set(app.textTolerancia,'visible','on');
                set(app.edit_tolerancia,'visible','on');
                set(app.textIteraciones,'visible','off');
                set(app.edit_iteraciones,'visible','off');
            else
                set(app.textIteraciones,'visible','on');
                set(app.edit_iteraciones,'visible','on');
                set(app.textTolerancia,'visible','off');
                set(app.edit_tolerancia,'visible','off ');
            end
            
            
        end

        % Button pushed function: pushbuttonCalcular
        function button_Calcular_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                funcion=app.edit_funcion.Value;
                valorRaiz = app.edit_valorInicial.Value;
                
                % Valida si la función es válida, si no, el catch lanza la excepción 
                fx= (get(handles.edit_funcion,'String'));
                funTemp=str2sym(fx); 
                
                if  isempty(fx)
                    warndlg('Debe ingresar la función F(x)','Precaución');    
                else
                    if(valorRaiz <= 0)
                        warndlg('El valor inicial debe ser positivo y mayor a cero', 'Precaución');
                    elseif(strcmp(app.DatosDropDown.Value,"Tolerancia")) 
                        if(app.edit_tolerancia.Value <= 0) 
                            warndlg('El valor de la tolerancia debe ser positivo y mayor a cero', 'Precaución');
                        else    
                            f = inline(funcion);
                            [respuesta]=derivada(f);
                            df = inline(respuesta);
                            tolerancia = app.edit_tolerancia.Value;
                            bandera=true;
                            n=0;
                            divisorDecimales=1;
                            
                            % Cuenta la cantidad de decimales que quiere el usuario para dividir
                            while(n<tolerancia)
                                divisorDecimales=(divisorDecimales*10);
                                n=n+1;
                            end
                            
                            % Se dividen los decimales para sacar la tolerancia
                            cantidadDecimales= 0.1/(divisorDecimales);
                            n=0;
                            
                            while (bandera)             
                                valorRaizAnterior=valorRaiz - rem(valorRaiz,cantidadDecimales);
                                valorRaiz = valorRaiz - (f(valorRaiz) / df(valorRaiz));   
                                valorRaizTruncado=valorRaiz - rem(valorRaiz,cantidadDecimales);
                                
                                % Revisa la cantidad de decimales
                                if(valorRaizAnterior==valorRaizTruncado) 
                                   bandera=false; 
                                end
                                n=n+1;
                            end
                            
                            set(handles.TextAreaResultado,'String',sprintf('P%d= %9.10f', n, valorRaiz));
                        end
                    else
                        if(app.edit_iteraciones.Value < 0) 
                            warndlg('El valor de las iteraciones debe ser positivo, mayor o igual a 0', 'Precaución');
                        else  
                            format long;
                            % Transforma el String en Funcion
                            f = inline(funcion); 
                            [respuesta]=derivada(f);
                            % Transforma el String en Funcion
                            df = inline(respuesta);
                            
                            n = 0;
                            iteraciones=app.edit_iteraciones.Value;
                            
                            % Repite el metodo para las iteraciones 
                            while (iteraciones>n)                  
                                valorRaiz = (valorRaiz - ((f(valorRaiz)) / (df(valorRaiz))));
                                n=n+1;
                            end
                            
                            set(handles.TextAreaResultado,'String',sprintf('P%d= %9.10f', iteraciones, valorRaiz));
                        end
                    end
                end
            catch ex
                warndlg('Hubo un error al leer la función F(x), revise que los datos ingresados sean correctos', 'Precaución');
            end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_NEWTON';
            app.figure1.Tag = 'figure1';
            app.MetododeNewtonPanel = uipanel(app.figure1);
            app.MetododeNewtonPanel.Title = 'Metodo de Newton';
            app.button_Regresar = uibutton(app.MetododeNewtonPanel, 'push');
            app.button_Regresar.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.button_Regresar.Tag = 'regresarbutton';
            app.button_Regresar.Text = 'Regresar';
            app.TextAreaResultado = uitextarea(app.MetododeNewtonPanel);
            app.TextAreaResultado.Tag = 'TextAreaResultado';
            app.TextAreaResultado.Editable = 'off';
            app.text_Resultado = uilabel(app.MetododeNewtonPanel);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            app.pushbuttonCalcular = uibutton(app.MetododeNewtonPanel, 'push');
            app.pushbuttonCalcular.ButtonPushedFcn = createCallbackFcn(app, @button_Calcular_Callback, true);
            app.pushbuttonCalcular.Tag = 'pushbutton_Aceptar';
            app.pushbuttonCalcular.Visible = 'off';
            app.pushbuttonCalcular.Text = 'Calcular';
            app.edit_iteraciones = uieditfield(app.MetododeNewtonPanel, 'numeric');
            app.edit_iteraciones.Visible = 'off';
            app.edit_iteraciones.Value = 1;
            app.textIteraciones = uilabel(app.MetododeNewtonPanel);
            app.textIteraciones.Tag = 'text6';
            app.textIteraciones.Visible = 'off';
            app.textIteraciones.Text = 'Ingrese el número de iteraciones:';
            app.edit_tolerancia = uieditfield(app.MetododeNewtonPanel, 'numeric');
            app.edit_tolerancia.Visible = 'off';
            app.edit_tolerancia.Value = 1;
            app.textTolerancia = uilabel(app.MetododeNewtonPanel);
            app.textTolerancia.Tag = 'text4';
            app.textTolerancia.Visible = 'off';
            app.textTolerancia.Text = 'Ingrese la cantidad de decimales exactos en un numero positivo:';
            app.textFuncion = uilabel(app.MetododeNewtonPanel);
            app.textFuncion.Tag = 'text3';
            app.textFuncion.Visible = 'off';
            app.textFuncion.Text = 'Ingrese la función de F(x) :';
            app.edit_valorInicial = uieditfield(app.MetododeNewtonPanel, 'numeric');
            app.edit_valorInicial.Visible = 'off';
            app.edit_valorInicial.Value = 1;
            app.textValorInicial = uilabel(app.MetododeNewtonPanel);
            app.textValorInicial.Tag = 'text5';
            app.textValorInicial.Visible = 'off';
            app.textValorInicial.Text = 'Ingrese el valor de inicio(si no tiene déjelo en 1):';
            app.edit_funcion = uieditfield(app.MetododeNewtonPanel, 'text');
            app.edit_funcion.Tag = 'edit_funcion';
            app.edit_funcion.Visible = 'off';
            app.pushbutton_Limpiar_2 = uibutton(app.MetododeNewtonPanel, 'push');
            app.pushbutton_Limpiar_2.ButtonPushedFcn = createCallbackFcn(app, @button_Limpiar_Callback, true);
            app.pushbutton_Limpiar_2.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar_2.Text = 'Limpiar';
            app.pushbuttonAceptar = uibutton(app.MetododeNewtonPanel, 'push');
            app.pushbuttonAceptar.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.pushbuttonAceptar.Text = 'Aceptar';
            app.DatosDropDown = uidropdown(app.MetododeNewtonPanel);
            app.DatosDropDown.Items = {'Tolerancia', 'Numero de iteraciones'};
            app.DatosDropDown.Value = 'Tolerancia';
            app.QudeseacalcularLabel = uilabel(app.MetododeNewtonPanel);
            app.QudeseacalcularLabel.Text = '¿Qué desea calcular?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Newton_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_NEWTON_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end