classdef GUI_RCS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        ReglaCompuestadeSimpsonPanel  matlab.ui.container.Panel
        pushbutton_Calcular           matlab.ui.control.Button
        edit_ValorB                   matlab.ui.control.EditField
        edit_Funcion                  matlab.ui.control.EditField
        edit_ValorA                   matlab.ui.control.EditField
        edit_ValorN                   matlab.ui.control.EditField
        pushbutton_Limpiar            matlab.ui.control.Button
        text_Funcion                  matlab.ui.control.Label
        text_ValorA                   matlab.ui.control.Label
        text_ValorB                   matlab.ui.control.Label
        text_ValorN                   matlab.ui.control.Label
        pushbutton_Regresar           matlab.ui.control.Button
        TextAreaBackR                 matlab.ui.control.TextArea
        text_ValorH                   matlab.ui.control.Label
        text_ValorIntegral            matlab.ui.control.Label
        text_Error                    matlab.ui.control.Label
        textArea_ValorH               matlab.ui.control.Label
        textArea_Error_R              matlab.ui.control.Label
        textArea_ValorIntegral_R      matlab.ui.control.Label
        TextAreaBackD                 matlab.ui.control.TextArea
        text_Derivada1                matlab.ui.control.Label
        text_Derivada2                matlab.ui.control.Label
        text_Derivada3                matlab.ui.control.Label
        text_Derivada4                matlab.ui.control.Label
        textArea_Derivada1            matlab.ui.control.Label
        textArea_Derivada2            matlab.ui.control.Label
        textArea_Derivada3            matlab.ui.control.Label
        textArea_Derivada4            matlab.ui.control.Label
    end

    
    methods (Access = private)

        function calculaSimpson(app, fx, a, b, n)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Da formato de expresion a fx
            f = inline(fx);
            
            derivada1=inline(derivada(f));      
            derivada2=inline(derivada(derivada1));
            derivada3=inline(derivada(derivada2));
            derivada4=inline(derivada(derivada3));
            set(handles.textArea_Derivada1,'String',char(derivada1));
            set(handles.textArea_Derivada2,'String',char(derivada2));
            set(handles.textArea_Derivada3,'String',char(derivada3));
            set(handles.textArea_Derivada4,'String',char(derivada4));
        
            % Aplica formula de h
            h=(b-a)/n;
            
            % Imprime el valor de h
            set(handles.textArea_ValorH,'String',sprintf('%9.9f',h));
            
            for k=1:1:n
                x(k)=a+k*h;
                y(k)=f(x(k));
            end
            so=0;se=0;
            for k=1:1:n-1
                if rem(k,2)==1
                    so=so+y(k); % Suma pares
                else
                    se=se+y(k); % Suma impares
                end
            end
            
            % Convierte la derivada4 en expresion
            gx = derivada4;
            
            % Aplica formula del punto medio
            puntoMedio= (a+b)/2; 
            
            %Evalua en la 4ta derivada el valor medio calculado
            errorDerivada=gx(puntoMedio); 
            
            % Calcula el error
            error=-((b-a)/180)*h^4*errorDerivada;
        
            % Imprime el valor del error
            set(handles.textArea_Error_R,'String',sprintf('%9.10f',error)); 
            
            % Aplica la formula de la RCS
            answer=h/3*(f(a)+f(b)+4*so+2*se);
            
            % Imprime valor de la aproximacion
            set(handles.textArea_ValorIntegral_R,'String',sprintf('%9.10f',answer));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_RCS_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_RCS (see VARARGIN)
            
            % Choose default command line output for GUI_RCS
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_Limpiar
        function pushbutton_LimpiarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia la información que haya ingresado el usuario
            set(handles.edit_Funcion,'String','');
            set(handles.edit_ValorA,'String','');
            set(handles.edit_ValorB,'String','');
            set(handles.edit_ValorN,'String','');
            
            % Limpia la información que haya como resultado 
            set(handles.textArea_ValorH,'String','');
            set(handles.textArea_ValorIntegral_R,'String','');
            set(handles.textArea_Error_R,'String','');
            set(handles.textArea_Derivada1,'String','');
            set(handles.textArea_Derivada2,'String','');
            set(handles.textArea_Derivada3,'String','');
            set(handles.textArea_Derivada4,'String','');
        end

        % Button pushed function: pushbutton_Calcular
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                syms x;
                fx= (get(handles.edit_Funcion,'String'));
                
                % Valida si la función es válida, si no, el catch lanza la excepción 
                funTemp=str2sym(fx); 
                
                % Obtiene los datos ingresados por el usuario
                a_valida=(get(handles.edit_ValorA,'String'));
                b_valida=(get(handles.edit_ValorB,'String'));
                n_valida=(get(handles.edit_ValorN,'String'));
                
                % Convierte los datos ingresados de strings a doble
                a= str2double(a_valida);
                b= str2double(b_valida);
                n= str2double(n_valida);
                
                if  isempty(fx)
                    warndlg('Debe ingresar la función','Precaución');    
                
                elseif isempty(a_valida)
                    warndlg('Debe ingresar el valor numérico del intervalo A','Precaución');
                
                elseif isnan(a) 
                    if(strcmp(a_valida, 'pi') || strcmp(a_valida, 'π'))
                        % Calcula la Regla Compuesta de Simpson cuando A sea pi
                        calculaSimpson(app, fx, pi, b, n);
                    else
                       warndlg('El valor numérico del intervalo A es incorrecto','Precaución'); 
                    end
                
                elseif isempty(b_valida)
                    warndlg('Debe ingresar el valor numérico del intervalo B','Precaución');
                elseif b <= a
                    warndlg('El inicio del intervalo (A) debe ser menor que el final del intervalo (B).','Precaución');
                elseif isnan(b)
                    if(strcmp(b_valida, 'pi') || strcmp(b_valida, 'π'))
                        % Calcula la Regla Compuesta de Simpson cuando B sea pi
                        calculaSimpson(app, fx, a, pi, n);
                    else
                       warndlg('El valor numérico del intervalo B es incorrecto','Precaución'); 
                    end    
               
                elseif isempty(n_valida)
                    warndlg('Debe ingresar el valor numérico N','Precaución');
                
                elseif isnan(n)
                    warndlg('El valor numérico de N es incorrecto','Precaución'); 
                
                elseif rem(n,2)==1
                    warndlg('N debe ser par','Precaución');  
                
                else
                    % Calcula la Regla Compuesta de Simpson
                     calculaSimpson(app, fx, a, b, n);
                end
                
            catch ex
                warndlg('Hubo un error al leer la función F(x), revise que los datos ingresados sean correctos', 'Precaución');
            end
            
        end

        % Button pushed function: pushbutton_Regresar
        function pushbutton_RegresarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closereq();
            GUI_Menu_App
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_RCS';
            app.figure1.Tag = 'figure1';
            app.ReglaCompuestadeSimpsonPanel = uipanel(app.figure1);
            app.ReglaCompuestadeSimpsonPanel.Title = 'Regla Compuesta de Simpson';
            app.pushbutton_Calcular = uibutton(app.ReglaCompuestadeSimpsonPanel, 'push');
            app.pushbutton_Calcular.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.pushbutton_Calcular.Tag = 'pushbutton_Calcular';
            app.pushbutton_Calcular.Text = 'Calcular';
            app.edit_ValorB = uieditfield(app.ReglaCompuestadeSimpsonPanel, 'text');
            app.edit_ValorB.Tag = 'edit_ValorB';
            app.edit_ValorB.Tooltip = 'Campo Numerico.';
            app.edit_Funcion = uieditfield(app.ReglaCompuestadeSimpsonPanel, 'text');
            app.edit_Funcion.Tag = 'edit_Funcion';
            app.edit_ValorA = uieditfield(app.ReglaCompuestadeSimpsonPanel, 'text');
            app.edit_ValorA.Tag = 'edit_ValorA';
            app.edit_ValorN = uieditfield(app.ReglaCompuestadeSimpsonPanel, 'text');
            app.edit_ValorN.Tag = 'edit_ValorN';
            app.pushbutton_Limpiar = uibutton(app.ReglaCompuestadeSimpsonPanel, 'push');
            app.pushbutton_Limpiar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_LimpiarButtonPushed, true);
            app.pushbutton_Limpiar.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar.Text = 'Limpiar';
            app.text_Funcion = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Funcion.Tag = 'text_Funcion';
            app.text_Funcion.Text = 'Ingrese la función F(x) (omita mayusculas)';
            app.text_ValorA = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_ValorA.Tag = 'text_ValorA';
            app.text_ValorA.Text = 'Valor numérico intervalo A';
            app.text_ValorB = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_ValorB.Tag = 'text_ValorB';
            app.text_ValorB.Text = 'Valor numéricointervalo B';
            app.text_ValorN = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_ValorN.Tag = 'text_ValorN';
            app.text_ValorN.Text = 'Valor numérico  N: PAR';
            app.pushbutton_Regresar = uibutton(app.ReglaCompuestadeSimpsonPanel, 'push');
            app.pushbutton_Regresar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_RegresarButtonPushed, true);
            app.pushbutton_Regresar.Tag = 'pushbutton_Regresar';
            app.pushbutton_Regresar.Text = 'Regresar';
            app.TextAreaBackR = uitextarea(app.ReglaCompuestadeSimpsonPanel);
            app.TextAreaBackR.Tag = 'TextAreaBackR';
            app.text_ValorH = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_ValorH.Tag = 'text_ValorH';
            app.text_ValorH.Text = 'Valor H:';
            app.text_ValorIntegral = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_ValorIntegral.Tag = 'text5';
            app.text_ValorIntegral.Text = 'Valor Integral RCS:';
            app.text_Error = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Error.Tag = 'text7';
            app.text_Error.Text = ' El error es:';
            app.textArea_ValorH = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_ValorH.Tag = 'textArea_ValorH';
            app.textArea_ValorH.Text = '';
            app.textArea_Error_R = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_Error_R.Tag = 'textArea_Error_R';
            app.textArea_Error_R.Text = '';
            app.textArea_ValorIntegral_R = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_ValorIntegral_R.Tag = 'textArea_ValorIntegral_R';
            app.textArea_ValorIntegral_R.Text = '';
            app.TextAreaBackD = uitextarea(app.ReglaCompuestadeSimpsonPanel);
            app.TextAreaBackD.Tag = 'TextAreaBackD';
            app.text_Derivada1 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Derivada1.Tag = 'text_Derivada1';
            app.text_Derivada1.Text = 'Derivada 1:';
            app.text_Derivada2 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Derivada2.Tag = 'text_Derivada2';
            app.text_Derivada2.Text = 'Derivada 2:';
            app.text_Derivada3 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Derivada3.Tag = 'text_Derivada3';
            app.text_Derivada3.Text = 'Derivada 3:';
            app.text_Derivada4 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.text_Derivada4.Tag = 'text_Derivada4';
            app.text_Derivada4.Text = 'Derivada 4:';
            app.textArea_Derivada1 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_Derivada1.Tag = 'textArea_Derivada1';
            app.textArea_Derivada1.Text = '';
            app.textArea_Derivada2 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_Derivada2.Tag = 'textArea_Derivada2';
            app.textArea_Derivada2.Text = '';
            app.textArea_Derivada3 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_Derivada3.Tag = 'textArea_Derivada3';
            app.textArea_Derivada3.Text = '';
            app.textArea_Derivada4 = uilabel(app.ReglaCompuestadeSimpsonPanel);
            app.textArea_Derivada4.Tag = 'textArea_Derivada4';
            app.textArea_Derivada4.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_RCS_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_RCS_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end