classdef GUI_TrazadorCubico_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MetodoTrazadorCubicoPanel       matlab.ui.container.Panel
        textoFuncion                    matlab.ui.control.Label
        uitable1                        matlab.ui.control.Table
        funcionEdit                     matlab.ui.control.EditField
        QudatostieneLabel               matlab.ui.control.Label
        DatosDropDown                   matlab.ui.control.DropDown
        CantidaddepuntosEditFieldLabel  matlab.ui.control.Label
        cantidadPuntos                  matlab.ui.control.NumericEditField
        pushbutton_Aceptar              matlab.ui.control.Button
        CalcularButton                  matlab.ui.control.Button
        TextAreaResultado               matlab.ui.control.TextArea
        text_Resultado                  matlab.ui.control.Label
        RegresarButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegresarButton
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            closereq();
            GUI_Menu_App
        end

        % Button pushed function: pushbutton_Aceptar
        function pushbutton_AceptarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            cantidadPuntosR = app.cantidadPuntos.Value;
            
            if(cantidadPuntosR <= 1)
                warndlg('La cantidad de puntos debe ser mayor a 2','Precaución'); 
            else
                if(strcmp(app.DatosDropDown.Value,"Imágenes"))
                    set(app.funcionEdit,'visible','off');
                    set(app.textoFuncion,'visible','off');
                    
                    filas=app.cantidadPuntos.Value; 
                    columnas=2;
                    
                    if(filas==0 || columnas==0)
                        warndlg('La cantidad de puntos debe ser mayor a 0','Precaución');
                        set(app.cantidadPuntos.Value,'String','');
                    else
                        set(app.CalcularButton,'visible','on');
                        tamano=cell(filas,columnas);
                        tamano(:,:)={'0'};
                        set(app.uitable1,'visible','on');
                        set(app.uitable1,'Data',tamano);
                        set(app.uitable1,'ColumnEditable',true(1,columnas));
                    end     
                else
                    set(app.funcionEdit,'visible','on');
                    set(app.textoFuncion,'visible','on');
                    filas=app.cantidadPuntos.Value; 
                    columnas=1;
                    set(app.CalcularButton,'visible','on');
                    tamano=cell(filas,columnas);
                    tamano(:,:)={'0'};
                    set(app.uitable1,'visible','on');
                    set(app.uitable1,'Data',tamano);
                    set(app.uitable1,'ColumnEditable',true(1,columnas));
                end
               
            end   
        end

        % Button pushed function: CalcularButton
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
               
                matriz=app.uitable1.Data;
              try
                % Cuando el usuario selecciona imagenes
                if(strcmp(app.DatosDropDown.Value,"Imágenes"))
               
                    matrizValida=str2double(matriz); 
                    
                    % Si la matriz es incorrecta
                    if(ismember(1, isnan(matrizValida))) 
                        % Envía una alerta de diálogo
                        warndlg('Debe ingresar valores numéricos para calcular el Trazador Cúbico','Precaución');
                    else 
                      matrizX=[];
                      matrizY=[];
                  
                      % Saca los puntos
                      for x = 1:size(matriz,1); 
                        matrizX(x,1)=str2double(matriz(x,1));  
                      end
                  
                      % Saca las imagenes
                      for y = 1:size(matriz,1); 
                        matrizY(y,1)=str2double(matriz(y,2));  
                      end
                    end
                % Cuando el usuario selecciona funcion 
                else
                     
                      % Valida si la función es válida, si no, el catch lanza la excepción 
                        fx= app.funcionEdit.Value;
                   
                        funTemp=str2sym(fx);    
                    
                     
                    matrizX=[];
                    matrizY=[];
                  
                    % Saca los puntos
                    for x = 1:size(matriz,1) ;
                        matrizX(x,1)=str2double(matriz(x,1));  
                    end
                  
                    syms x
                    f=str2func(['@(x)',fx]);
                  
                    % Saca las imagenes de los puntos 
                    for x = 1:size(matrizX,1);
                        matrizY(x,1)=subs(f,matrizX(x,1));
                    end
                   
                end
                
                %Datos
                matrizValores =[matrizX matrizY] ;%igualamos las matrices
                
                tamanoMatriz=length(matrizValores(1,:));%determinamos su tamaño
    
                for i=1:tamanoMatriz;
                    a(i)=matrizValores(2,i); % igualamos las imagenes a los a
                end
                
                for i=1:tamanoMatriz-1;
                    h(i)=matrizValores(1,i+1)-matrizValores(1,i);%determinamos los h
                end
                
                for i=2:tamanoMatriz-1;
                    alfa(i)=3/h(i)*(a(i+1)-a(i))-3/h(i-1)*(a(i)-a(i-1));
                end
                
                l(1)=1;
                mu(1)=0;
                z(1)=0;
                
                for i=2:tamanoMatriz-1;
                    l(i)=2*(matrizValores(1,i+1)-matrizValores(1,i-1))-h(i-1)*mu(i-1);
                    mu(i)=h(i)/l(i);
                    z(i)=(alfa(i)-h(i-1)*z(i-1))/l(i);
                end
                
                l(tamanoMatriz)=1;
                z(tamanoMatriz)=0;
                c(tamanoMatriz)=0;
                
                for i=tamanoMatriz-1:-1:1;
                    c(i)=z(i)-mu(i)*c(i+1);
                    b(i)=(a(i+1)-a(i))/h(i)-h(i)*(c(i+1)+2*c(i))/3;
                    d(i)=(c(i+1)-c(i))/(3*h(i));
                end
                %Calculamos el resultado de los polinomios del metodo
                for i=1:tamanoMatriz-1;
                    matrizValores=matrizValores(1,i):0.1:matrizValores(1,i+1);
                    y=a(i)+b(i)*(matrizValores-matrizValores(1,i))+c(i)*(matrizValores-matrizValores(1,i)).^2+d(i)*(matrizValores-matrizValores(1,i)).^3;
                    resultado=""+y;
                end
               
                app.TextAreaResultado.Value =""+ resultado;
                
                set(app.text_Resultado,'visible','on');
                set(app.TextAreaResultado,'visible','on');
             catch
                warndlg('Hubo un error al leer la función F(x), revise que los datos ingresados sean correctos', 'Precaución');
             end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetodoTrazadorCubicoPanel = uipanel(app.UIFigure);
            app.MetodoTrazadorCubicoPanel.Title = 'Metodo Trazador Cubico';
            app.textoFuncion = uilabel(app.MetodoTrazadorCubicoPanel);
            app.textoFuncion.Visible = 'off';
            app.textoFuncion.Text = 'Ingrese la función ';
            app.uitable1 = uitable(app.MetodoTrazadorCubicoPanel);
            app.uitable1.Visible = 'off';
            app.uitable1.Tag = 'uitable1';
            app.funcionEdit = uieditfield(app.MetodoTrazadorCubicoPanel, 'text');
            app.funcionEdit.Visible = 'off';
            app.QudatostieneLabel = uilabel(app.MetodoTrazadorCubicoPanel);
            app.QudatostieneLabel.Text = '¿Qué datos tiene?';
            app.DatosDropDown = uidropdown(app.MetodoTrazadorCubicoPanel);
            app.DatosDropDown.Items = {'Imágenes', 'Función'};
            app.DatosDropDown.Value = 'Imágenes';
            app.CantidaddepuntosEditFieldLabel = uilabel(app.MetodoTrazadorCubicoPanel);
            app.CantidaddepuntosEditFieldLabel.Text = 'Cantidad de puntos';
            app.cantidadPuntos = uieditfield(app.MetodoTrazadorCubicoPanel, 'numeric');
            app.cantidadPuntos.Value = 2;
            app.pushbutton_Aceptar = uibutton(app.MetodoTrazadorCubicoPanel, 'push');
            app.pushbutton_Aceptar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AceptarButtonPushed, true);
            app.pushbutton_Aceptar.Text = 'Aceptar';
            app.CalcularButton = uibutton(app.MetodoTrazadorCubicoPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.CalcularButton.Visible = 'off';
            app.CalcularButton.Text = 'Calcular';
            app.TextAreaResultado = uitextarea(app.MetodoTrazadorCubicoPanel);
            app.TextAreaResultado.Tag = 'textArea_Resultado';
            app.TextAreaResultado.Editable = 'off';
            app.TextAreaResultado.Visible = 'off';
            app.text_Resultado = uilabel(app.MetodoTrazadorCubicoPanel);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Visible = 'off';
            app.text_Resultado.Text = 'Resultado';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.RegresarButton.Text = 'Regresar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TrazadorCubico_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end